classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartmonitorButton              matlab.ui.control.Button
        StopmonitorButton               matlab.ui.control.Button
        ADCFullscalerangenALabel        matlab.ui.control.Label
        ADCFullscalerangenADropDown     matlab.ui.control.DropDown
        SamplerateHzLabel               matlab.ui.control.Label
        SamplerateHzDropDown            matlab.ui.control.DropDown
        PulsewidthsLabel                matlab.ui.control.Label
        PulsewidthsDropDown             matlab.ui.control.DropDown
        ConfigurationmodeDropDownLabel  matlab.ui.control.Label
        ConfigurationmodeDropDown       matlab.ui.control.DropDown
        GeneralSettingLabel             matlab.ui.control.Label
        MaxAcquisitionTimesEditFieldLabel  matlab.ui.control.Label
        MaxAcquisitionTimesEditField    matlab.ui.control.NumericEditField
        CurrentLEDsettingLabel          matlab.ui.control.Label
        REDmAEditFieldLabel             matlab.ui.control.Label
        REDmAEditField                  matlab.ui.control.NumericEditField
        IRmAEditFieldLabel              matlab.ui.control.Label
        IRmAEditField                   matlab.ui.control.NumericEditField
        UIAxesir                        matlab.ui.control.UIAxes
        MeasureTemperatureButton        matlab.ui.control.Button
        TemperaturemeasuredLabel        matlab.ui.control.Label
        DisconnectedLampLabel           matlab.ui.control.Label
        DisconnectedLamp                matlab.ui.control.Lamp
        UIAxesred                       matlab.ui.control.UIAxes
        NotMonitoringLabel              matlab.ui.control.Label
        OpenreadmefileButton            matlab.ui.control.Button
        OpentutorialButton              matlab.ui.control.Button
        ProcessDataButton               matlab.ui.control.Button
    end

    
    properties (Access = public)   
        myMAX30101;
        myHID
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

           MAX30101AssemblyPathname = 'C:\Program Files (x86)\Maxim Integrated\MAX30101EVKit';
            if (exist(fullfile(MAX30101AssemblyPathname, 'MAX30101.dll'), 'file') == 0)
                fprintf('Missing MAX30101EVKIT interface library.\n');
                return;
            end
            fprintf('Connecting to MAX30101EVKIT interface library\n  at %s\n', MAX30101AssemblyPathname);
            %Rende visibile la classe MAX30101 a Matlab
            NET.addAssembly(fullfile(MAX30101AssemblyPathname, 'MAX30101.dll'));
            %Create MAX30101 object
            fprintf('Creating Maxim.MAX30101.MAX30101 object\n');
            %Creato a partire dall'oggetto "creato" alla riga 80: app.myMAX30101 è una
            %classe!
            app.myMAX30101=Maxim.MAX30101.MAX30101();
            fprintf('Creating Maxim.MAX30101.HID object\n');
            app.myHID=Maxim.MAX30101.HID();
            %Collegare l'evaluation kit
            % Required: connect USB interface to MAX30101EVKIT
            fprintf('Connecting USB interface to MAX30101EVKIT\n');
            app.myMAX30101.myHID = app.myHID;
            % Required: connect to MAX30101EVKIT hardware
            fprintf('Connecting to MAX30101EVKIT hardware...\n');
            for trial=0:100
                try
                    %pause(1) % delay at least 1 second
                    app.app.myMAX30101.myHID.findHIDs();
                    if (app.myMAX30101.myHID.isConnected())
                        break
                    end
                catch me
                    disp(me)
                    disp(app.myHID.DebugMessage)
                end
            end
            %
            % Required: check USB interface connection status
            if (app.myMAX30101.myHID.isConnected())
                disp('Succesfuly connected');
                app.DisconnectedLampLabel.Text = 'Connected';
                app.DisconnectedLamp.Color =[0,1,0];
            else
                msgbox('Did not connect to MAX30101EVKIT hardware. Check USB.\n');
                return;
            end
        end

        % Button pushed function: StartmonitorButton
        function StartmonitorButtonPushed(app, event)
%             global app.myMAX30101;
            global myHID;
            % Parameter taken from GUI
            app.myMAX30101.Mode = app.ConfigurationmodeDropDown.Value;
            app.myMAX30101.SampleRate_Hz = str2double(app.SamplerateHzDropDown.Value);
            app.myMAX30101.PulseWidth_us = str2double(app.PulsewidthsDropDown.Value);
            app.myMAX30101.ADCFullScaleRange_nA = str2double(app.ADCFullscalerangenADropDown.Value);
            app.myMAX30101.RedLEDCurrent_mA = double(app.REDmAEditField.Value);
            app.myMAX30101.IRLEDCurrent_mA = double(app.IRmAEditField.Value);
            %limit for acquisition( in second)
            app.myMAX30101.TimeLimit_seconds = double(app.MaxAcquisitionTimesEditField.Value);
            % Default parameter(it is not recommended changing this parameters)
            app.myMAX30101.SampleAverage_n = 1;
            app.myMAX30101.PilotPALEDCurrent_mA = 10.15625;
            app.myMAX30101.numProxThreshold_Value = 255;
            %1:red, 2:ir, 3:green, 4:green
            app.myMAX30101.LEDslot1 = 1;
            app.myMAX30101.LEDslot2 = 2;
            app.myMAX30101.LEDslot3 = 0;
            app.myMAX30101.LEDslot4 = 0;
            %Aggiunta listener
            addlistener(app.myMAX30101,'RegisterRead',@OnRegisterRead_console);
            addlistener(app.myMAX30101,'RegisterWrite',@OnRegisterWrite_console);
            addlistener(app.myMAX30101,'StreamingStarted',@(src,evtdata)OnStreamingStarted(src,evtdata,app.NotMonitoringLabel));
            addlistener(app.myMAX30101,'StreamingEnded',@(src,evtdata)OnStreamingEnded(src,evtdata,app.NotMonitoringLabel));
            %Passo alla callback l'handle per gli assi, in modo da stampare li sopra il risultato!!
            addlistener(app.myMAX30101,'PartialArrayIntAvailable',@(src,evtdata)OnPartialArrayIntAvailable_console(src,evtdata,app.UIAxesir,app.UIAxesred));
            addlistener(app.myMAX30101,'FinalArrayIntAvailable',@(src,evtdata)OnFinalArrayIntAvailable(src,evtdata,app.UIAxesir,app.UIAxesred));
            %Avvio il monitoraggio (si ferma o al raggiungimento del limite inserito o quando viene premuto il tasto stop monitoring)
            app.myMAX30101.StartMonitoring();
            while (app.myMAX30101.IsMonitoring)
                % waiting for sampling to finish...
                pause(1.5) % delay at least 1 second to give event handlers time to work
            end
        end

        % Button pushed function: MeasureTemperatureButton
        function MeasureTemperatureButtonPushed(app, event)
            global temperature;
            addlistener(app.myMAX30101,'TemperatureMeasured',@OnTemperatureMeasured_console);
            app.myMAX30101.Measure_SensorTemperatureDegreesC();
            app.TemperaturemeasuredLabel.Text = ['T= ',num2str(temperature),' °C'];
        end

        % Callback function
        function ConnectSwitchValueChanged(app, event)
            
        end

        % Button pushed function: StopmonitorButton
        function StopmonitorButtonPushed(app, event)
           app.myMAX30101.StopMonitoring();
        end

        % Button pushed function: OpenreadmefileButton
        function OpenreadmefileButtonPushed(app, event)
            winopen('readme.txt');
        end

        % Button pushed function: OpentutorialButton
        function OpentutorialButtonPushed(app, event)
            winopen('tutorial.pdf');
        end

        % Button pushed function: ProcessDataButton
        function ProcessDataButtonPushed(app, event)
            matlab.apputil.run(processData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartmonitorButton = uibutton(app.UIFigure, 'push');
            app.StartmonitorButton.ButtonPushedFcn = createCallbackFcn(app, @StartmonitorButtonPushed, true);
            app.StartmonitorButton.Text = 'Start monitor';
            app.StopmonitorButton = uibutton(app.UIFigure, 'push');
            app.StopmonitorButton.ButtonPushedFcn = createCallbackFcn(app, @StopmonitorButtonPushed, true);
            app.StopmonitorButton.Text = 'Stop monitor';
            app.ADCFullscalerangenALabel = uilabel(app.UIFigure);
            app.ADCFullscalerangenALabel.Text = 'ADC Full scale range (nA)';
            app.ADCFullscalerangenADropDown = uidropdown(app.UIFigure);
            app.ADCFullscalerangenADropDown.Items = {'2048', '4096', '8192', '16384'};
            app.ADCFullscalerangenADropDown.Value = '8192';
            app.SamplerateHzLabel = uilabel(app.UIFigure);
            app.SamplerateHzLabel.Text = 'Sample rate (Hz)';
            app.SamplerateHzDropDown = uidropdown(app.UIFigure);
            app.SamplerateHzDropDown.Items = {'50', '100', '200', '400', '600', '800', '1000', '1600', '3200'};
            app.SamplerateHzDropDown.ItemsData = {'50', '100', '200', '400', '600', '800', '1000', '1600', '3200'};
            app.SamplerateHzDropDown.Value = '100';
            app.PulsewidthsLabel = uilabel(app.UIFigure);
            app.PulsewidthsLabel.Text = 'Pulse width (μs)';
            app.PulsewidthsDropDown = uidropdown(app.UIFigure);
            app.PulsewidthsDropDown.Items = {'50', '100', '200', '400'};
            app.PulsewidthsDropDown.Value = '400';
            app.ConfigurationmodeDropDownLabel = uilabel(app.UIFigure);
            app.ConfigurationmodeDropDownLabel.Text = {'Configuration mode'; ''};
            app.ConfigurationmodeDropDown = uidropdown(app.UIFigure);
            app.ConfigurationmodeDropDown.Items = {'LED', 'SPO2', 'HR'};
            app.ConfigurationmodeDropDown.Value = 'LED';
            app.GeneralSettingLabel = uilabel(app.UIFigure);
            app.GeneralSettingLabel.Text = {'General Setting'; ''};
            app.MaxAcquisitionTimesEditFieldLabel = uilabel(app.UIFigure);
            app.MaxAcquisitionTimesEditFieldLabel.Text = {'Max Acquisition Time (s)'; ''};
            app.MaxAcquisitionTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxAcquisitionTimesEditField.Limits = [0 Inf];
            app.MaxAcquisitionTimesEditField.Value = 60;
            app.CurrentLEDsettingLabel = uilabel(app.UIFigure);
            app.CurrentLEDsettingLabel.Text = 'Current LED setting';
            app.REDmAEditFieldLabel = uilabel(app.UIFigure);
            app.REDmAEditFieldLabel.Text = 'RED(mA)';
            app.REDmAEditField = uieditfield(app.UIFigure, 'numeric');
            app.REDmAEditField.Limits = [0 50];
            app.REDmAEditField.ValueDisplayFormat = '%11.7g';
            app.REDmAEditField.Value = 10.15625;
            app.IRmAEditFieldLabel = uilabel(app.UIFigure);
            app.IRmAEditFieldLabel.Text = 'IR(mA)';
            app.IRmAEditField = uieditfield(app.UIFigure, 'numeric');
            app.IRmAEditField.Limits = [0 50];
            app.IRmAEditField.ValueDisplayFormat = '%11.7g';
            app.IRmAEditField.Value = 10.15625;
            app.UIAxesir = uiaxes(app.UIFigure);
            title(app.UIAxesir, 'Infra-Red data')
            xlabel(app.UIAxesir, 'sample')
            ylabel(app.UIAxesir, 'Amplitude')
            app.MeasureTemperatureButton = uibutton(app.UIFigure, 'push');
            app.MeasureTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureTemperatureButtonPushed, true);
            app.MeasureTemperatureButton.Text = 'Measure Temperature';
            app.TemperaturemeasuredLabel = uilabel(app.UIFigure);
            app.TemperaturemeasuredLabel.Text = 'Temperature measured';
            app.DisconnectedLampLabel = uilabel(app.UIFigure);
            app.DisconnectedLampLabel.Text = {'Disconnected'; ''};
            app.DisconnectedLamp = uilamp(app.UIFigure);
            app.UIAxesred = uiaxes(app.UIFigure);
            title(app.UIAxesred, {'Red data'; ''})
            xlabel(app.UIAxesred, 'sample')
            ylabel(app.UIAxesred, 'Amplitude')
            app.NotMonitoringLabel = uilabel(app.UIFigure);
            app.NotMonitoringLabel.Text = 'Not Monitoring';
            app.OpenreadmefileButton = uibutton(app.UIFigure, 'push');
            app.OpenreadmefileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenreadmefileButtonPushed, true);
            app.OpenreadmefileButton.Text = 'Open readme file';
            app.OpentutorialButton = uibutton(app.UIFigure, 'push');
            app.OpentutorialButton.ButtonPushedFcn = createCallbackFcn(app, @OpentutorialButtonPushed, true);
            app.OpentutorialButton.Text = {'Open tutorial'; ''};
            app.ProcessDataButton = uibutton(app.UIFigure, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessDataButtonPushed, true);
            app.ProcessDataButton.Text = {'Process Data'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end